classdef CrossAlignmentTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MainContainer            matlab.ui.container.GridLayout
        ImgContainer             matlab.ui.container.GridLayout
        CurrentlyShownTitle      matlab.ui.control.Label
        DisplayAxis              matlab.ui.control.UIAxes
        SettingsContainer        matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Img1Tab                  matlab.ui.container.Tab
        GridLayout5              matlab.ui.container.GridLayout
        InvertButton             matlab.ui.control.StateButton
        RotateEditField          matlab.ui.control.NumericEditField
        RotateEditFieldLabel     matlab.ui.control.Label
        ZoomSpinner              matlab.ui.control.Spinner
        ZoomSpinnerLabel         matlab.ui.control.Label
        ZPosSpinner              matlab.ui.control.Spinner
        ZPosSpinnerLabel         matlab.ui.control.Label
        CropButton               matlab.ui.control.Button
        Rect_SelectButton        matlab.ui.control.StateButton
        CentreYEditField         matlab.ui.control.NumericEditField
        CentreYEditFieldLabel    matlab.ui.control.Label
        CentreXEditField         matlab.ui.control.NumericEditField
        CentreXEditFieldLabel    matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.RangeSlider
        ContrastSliderLabel      matlab.ui.control.Label
        Img2Tab                  matlab.ui.container.Tab
        GridLayout5_2            matlab.ui.container.GridLayout
        InvertButton_2           matlab.ui.control.StateButton
        RotateEditField_2        matlab.ui.control.NumericEditField
        RotateEditField_2Label   matlab.ui.control.Label
        ZoomSpinner_2            matlab.ui.control.Spinner
        ZoomSpinner_2Label       matlab.ui.control.Label
        ZPosSpinner_2            matlab.ui.control.Spinner
        ZPosSpinner_2Label       matlab.ui.control.Label
        CropButton_2             matlab.ui.control.Button
        Rect_SelectButton_2      matlab.ui.control.StateButton
        CentreYEditField_2       matlab.ui.control.NumericEditField
        CentreYEditField_2Label  matlab.ui.control.Label
        CentreXEditField_2       matlab.ui.control.NumericEditField
        CentreXEditField_2Label  matlab.ui.control.Label
        ContrastSlider_2         matlab.ui.control.RangeSlider
        ContrastSlider_2Label    matlab.ui.control.Label
        TranslateTab             matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        scale_yEditField         matlab.ui.control.NumericEditField
        scale_yEditFieldLabel    matlab.ui.control.Label
        scale_xEditField         matlab.ui.control.NumericEditField
        scale_xEditFieldLabel    matlab.ui.control.Label
        offset_yEditField        matlab.ui.control.NumericEditField
        offset_yEditFieldLabel   matlab.ui.control.Label
        offset_xEditField        matlab.ui.control.NumericEditField
        offset_xEditFieldLabel   matlab.ui.control.Label
        DataPanel                matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        GridLayout7              matlab.ui.container.GridLayout
        ExportTypeDropDown       matlab.ui.control.DropDown
        ExportTypeDropDownLabel  matlab.ui.control.Label
        useROICheckBox           matlab.ui.control.CheckBox
        ExportButton             matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        ImgSelector              matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        img1_CI % Class that handles and stores the image data such that 
                % its easier to render and work with
        img2_CI % Class that handles and stores the image data such that 
                % its easier to render and work with
        img_Merge %class that handles and manipulates the merged RGB image
        img_Merge_Initiated = false % bool to check if we have a merge class present
        img1_cache %matricies storing the currently shown image
        img2_cache %matricies storing the currently shown image
        img1_ROI %ROI obj for image 1
        img2_ROI %ROI obj for image 2
        imgMerge_ROI %ROI obj for the imageMerge as used for selective export
        displayImg
        imgInit = false

    end
    
    %DEBUG METHODS
    methods (Access = public)
        function outputImageData = debugCheckImagePartExport(app,imageNo, imageCoords)
            %debug function to check export handdler is properly selecting
            %the area under a selected area
            %expects coords as d1lower, d2lower; d1 upper, d2 upper

            displaySpaceSize = app.img_Merge.getOverlayedSize();

            if imageNo == 1
               imageParams = struct( ...
                "inputSource","CAT",...
                "imageRotation", app.RotateEditField.Value,...
                "imageZoomRatio", app.img1_CI.getZoomRatio(),...
                "imageCentreOffset", [app.CentreYEditField.Value,app.CentreXEditField.Value], ...
                "imageCentreLoc",mean(app.img_Merge.img1Loc'));
               rawImgData = app.img1_CI.getFullScaleImgData();
            else
                imageParams = struct(...
                "inputSource","CAT",...
                "imageRotation",app.RotateEditField_2.Value,...
                "imageZoomRatio", app.img2_CI.getZoomRatio(),...
                "imageCentreOffset", [app.CentreYEditField_2.Value, app.CentreXEditField_2.Value],...
                "mergeScale", [app.scale_yEditField.Value,app.scale_xEditField.Value],...
                "imageCentreLoc",mean(app.img_Merge.img2Loc'));
                rawImgData = app.img2_CI.getFullScaleImgData();
            end


            AIE = alignedImageExporter(imageParams, displaySpaceSize);
            outputImageData = AIE.getAlignedAreaOfInterest(rawImgData{1}, imageCoords);
        
        end

    end

    methods (Access = private)
        
        %function to initalise the imgMerge
        function initaliseImgMerge(app)

            app.img_Merge = imgMerge(app.img1_cache, app.img2_cache, "img1Contrast",app.ContrastSlider.Value, "img2Contrast", ...
                app.ContrastSlider_2.Value, "offset_x",app.offset_xEditField.Value,"offset_y", app.offset_yEditField.Value, ...
                "scale_x", app.scale_xEditField.Value,"scale_y",app.scale_yEditField.Value);

            app.img_Merge_Initiated = true;
        end

        %function to crashZoom on crop area
        function cropZoom(app, imgNo)
            %first we need to get the position of the rectangle
            switch imgNo
                case 1
                    ROI = app.img1_ROI;
                    img_cache = app.img1_cache;
                    zoomSpinner = app.ZoomSpinner;
                    centX = app.CentreXEditField;
                    centY = app.CentreYEditField;
                    imgData = app.img1_CI;
                case 2 
                    ROI = app.img2_ROI;
                    img_cache = app.img2_cache;
                    zoomSpinner = app.ZoomSpinner_2;
                    centX = app.CentreXEditField_2;
                    centY = app.CentreYEditField_2;
                    imgData = app.img2_CI;
            end
            
            if isempty(ROI)
                return % short circuit if no rectangle
            end

            %get the dimetions of the ROI
            ROIPos = ROI.Position; % [xmin, ymin, width, height]
            %convert to centre and zoom
            ROICentre = [ROIPos(1)+(ROIPos(3)/2), ROIPos(2)+(ROIPos(4)/2)];
            imgSize = size(img_cache);
            ROIZoom = imgSize ./ [ROIPos(3), ROIPos(4)];
            Zoom = round(min(ROIZoom),"TieBreaker","minusinf"); %find the minimum zoom and round down

            %set controls appropriately then update the image (Which pulls
            %off the controls)
            zoomSpinner.Value = Zoom*zoomSpinner.Value;
            centY.Value = round(ROICentre(2),"TieBreaker","minusinf");
            centX.Value = round(ROICentre(1),"TieBreaker","minusinf");

            %interestingly this doesn't hit the callbacks,thats kinda
            %useful tho, we can now call it 
            app.zoomChanged(zoomSpinner.Value, imgNo, imgData);
            

        end

        %function to set contrast of display axis
        function setDisplayContrast(app, min, max)
            if min < max %protection
                app.DisplayAxis.CLim = [min,max];
            end
        end

       
        %function to update contrast based on currently selected image and
        %set contrast
        %note: imgNo is an optional overide when coming from the sliders
        %which causes the refresh merge Image to be updated properly
        function updateContrast(app,imgNo)
            %work out the current image
            switch app.ImgSelector.Value
                case "Img1"
                    limVals = app.ContrastSlider.Value;
                    app.setDisplayContrast(limVals(1),limVals(2));
                case "Img2"
                    limVals = app.ContrastSlider_2.Value;
                    app.setDisplayContrast(limVals(1),limVals(2));
                case "Merge"
                     % short circuit cos not needed
            end
            
            %update merge if needed
            if exist("imgNo","var")
                app.refreshMergeImage(imgNo)
            end
            if app.ImgSelector.Value == "Merge"
                app.updateImageCache(3);
            end
        end

        %Function To disable features requiring an individual image selected
        function disableImgSpecifics(app)
            app.LoadButton.Enable ="off";
        end
        %Function To enable features requiring an individual image selected
        function enableImgSpecifics(app)
            app.LoadButton.Enable="on";
        end
        
        %Function to run updates when a new image is loaded
        function newDataLoadedUpdates(app, imgUpdated)
            
            switch imgUpdated
                case 1
                    imgData = app.img1_CI;
                    tab=1;
                case 2
                    imgData = app.img2_CI;
                    tab=2;
                otherwise
                    error("ERROR: unexpected image updated")
            end

            %update labels
            app.updateImgTitle(imgData);

            %downsize the image
            imgData.generateDownScaleImage(); %update to background

            %update image controls
            app.updateImgControls(imgData,tab);

            %display the image 
            app.updateImageCache(tab);

        end

        %function to update data in the image cache
        function updateImageCache(app, imgUpdated)
            
            %first find which to update
            switch imgUpdated
                case 1
                    imgData = app.img1_CI;
                    zSpin = app.ZPosSpinner;
                    centre1 = app.CentreYEditField;
                    centre2 = app.CentreXEditField;

                    %update cache
                    targetCache = imgData.requestImg("zPlane",zSpin.Value,"centre_1",centre1.Value,"centre_2",centre2.Value);
                    app.img1_cache = targetCache;
                    
                    %update the merge if needed
                    app.refreshMergeImage(1);
                case 2
                    imgData = app.img2_CI;
                    zSpin = app.ZPosSpinner_2;
                    centre1 = app.CentreYEditField_2;
                    centre2 = app.CentreXEditField_2;

                    %update cache
                    targetCache = imgData.requestImg("zPlane",zSpin.Value,"centre_1",centre1.Value,"centre_2",centre2.Value);
                    app.img2_cache = targetCache;
                    
                    %update the merge if needed
                    app.refreshMergeImage(2);
                case 3 %3 -> merge
                    targetCache = app.img_Merge.getOverlayedImage();
            end


            %push to image
            if ~app.imgInit
                %initalise displayImg
                app.displayImg = imshow(targetCache,[],'Parent',app.DisplayAxis);
                app.imgInit = true;
            else
                app.displayImg.CData = targetCache;
            end

            %update the contrast: note: contrast is done by 
            if imgUpdated ~= 3
                app.updateContrast();
            end
        end
        
        %function to handle async downscaling and displaying of image 
        function asyncHandleDownScaleImage(app,imgUpdated, imgData)
            %queue up downscaling
            dsFuture = parfeval(backgroundPool, @imgData.generateDownScaleImage,0);
            
            %queue up displaying the image
            dispFuture = afterEach(dsFuture,@func,0);
            
        end

        %function to update the imgTitle based off of what image is
        %currently displayed, will find current imgData if not provided
        function updateImgTitle(app, imgData)
            
            %check to see if imgData exists
            if (~exist("imgData","var"))
                imgData = app.getCurrentImgData();
            end

            %check to see if data is present 
            if  isempty(imgData) || imgData == -1
                app.CurrentlyShownTitle.Text = "Load Data"; 
            else
                app.CurrentlyShownTitle.Text = imgData.getImgName();
            end
        end
        
        %function to update the image title to be the merge of both or
        %load stuff
        function updateMergeImgTitle(app)
            
            %work out title 1
            if isempty(app.img1_CI)
                img1_title = "No image 1";
            else
                img1_title = app.img1_CI.getImgName();
            end

            %work out title 2
            if isempty(app.img2_CI)
                img2_title = "No image 2";
            else
                img2_title = app.img2_CI.getImgName();
            end
            
            titleStr = strcat(img1_title, " merge ", img2_title);
            app.CurrentlyShownTitle.Text = titleStr;
        end
        
        %function to coordinate safely swapping img caches
        function swapImageSource(app, sourceIndex)
            switch sourceIndex
                case 1
                    targetCache = app.img1_cache;
                case 2
                    targetCache = app.img2_cache;
                case 3
                    targetCache = app.img_Merge.getOverlayedImage();
                    %reserve for merge
            end

            %update each ROI thing so it disappears if it ain't needed
            app.imgMerge_ROI = app.updateROIRectState(app.useROICheckBox.Value, "Merge", app.imgMerge_ROI, false);
            app.img1_ROI = app.updateROIRectState(app.Rect_SelectButton.Value,"Img1",app.img1_ROI);
            app.img2_ROI = app.updateROIRectState(app.Rect_SelectButton_2.Value,"Img2",app.img2_ROI);

            %protection for if the cache isn't filled yet
            if ~isempty(targetCache)
                app.displayImg.CData = targetCache;
                app.updateContrast();
            end
        end

        %function to get the currently selected image data 
        function imgData = getCurrentImgData(app)
            currImg = app.ImgSelector.Value;
            
            switch currImg
                case "Img1"
                    imgData = app.img1_CI;
                case "Img2"
                    imgData = app.img2_CI;
                otherwise
                    imgData = -1;
            end
            
        end
        
        %Function to update the image controls so that they're limited by
        %the metadata of the img data
        function updateImgControls(app,imgData,tabNo)
            
            %get the relevant metadata from imgData
            metadata = imgData.getImgControlsMetadata();
            
            %grab the right elements
            switch tabNo %consider connecting these as a dict/struct or smth
                case 1
                    ZSpin = app.ZPosSpinner;
                    centerY = app.CentreYEditField;
                    centerX = app.CentreXEditField;
                    contrast = app.ContrastSlider;
                    ZoomSpin = app.ZoomSpinner;
                case 2
                    ZSpin = app.ZPosSpinner_2;
                    centerY = app.CentreYEditField_2;
                    centerX = app.CentreXEditField_2;
                    contrast = app.ContrastSlider_2;
                    ZoomSpin = app.ZoomSpinner_2;
                otherwise
                    error("ERROR: unexpected tabNo in updateImgControls");
            end

            %set the values
            ZSpin.Limits = [1,metadata.zMax];
            ZSpin.Value = 1;
            contrast.Limits = [metadata.minIntensity, metadata.maxIntensity];
            contrast.Value = [metadata.minIntensity, metadata.maxIntensity];
            ZoomSpin.Limits=[1,floor(min(metadata.maxZoom,[],"all"))];
            centerY.Limits = metadata.centreLimits(:,1)';
            centerX.Limits = metadata.centreLimits(:,2)';


        end

        %Function to initialise & change acitivity state of ROI
        function ROIVar = toggleROI(app,state, ROIVar, fixedAspectRatio)
            arguments
                app 
                state 
                ROIVar 
                fixedAspectRatio = true;
            end
            
            %first we see if ROIvar exists
            if isempty(ROIVar)
                %no ROI currently so we need to initalise it
                ROIVar = drawrectangle(app.DisplayAxis);
                
                %so that crop zooms remain fixed
                if fixedAspectRatio
                    ROIVar.AspectRatio = 1;
                    ROIVar.FixedAspectRatio = 1; 
                end

            end

            if state == 1
                %so its active
                ROIVar.Visible = "on";
                ROIVar.InteractionsAllowed = "all";
            else
                ROIVar.Visible = "off";
                ROIVar.InteractionsAllowed = "none";
            end
        end
        
        %function to get the current offsets
        %returns [yOff, xOff]
        function offsets = getCurrentOffsets(app,imgIdx)

            switch imgIdx
                case 1
                    xOffset = app.CentreXEditField.Value;
                    yOffset = app.CentreYEditField.Value;
                case 2
                    xOffset = app.CentreXEditField_2.Value;
                    yOffset = app.CentreYEditField_2.Value;
            end
            offsets = [yOffset,xOffset];

        end

        %function to get the current offsets
        %returns yOffset, xOffset
        function setCurrentOffsets(app, imgIdx, yOffset, xOffset)
            switch imgIdx
                case 1
                     app.CentreXEditField.Value = xOffset;
                     app.CentreYEditField.Value = yOffset;
                case 2
                     app.CentreXEditField_2.Value = xOffset;
                     app.CentreYEditField_2.Value = yOffset;
            end
        end

        %function to update offset centre field limits
        function updateOffsetLimits(app, imgIdx, centreLimits)
            %note: centreLimits supplied [minD1,minD2;maxD1,maxD2]
            %note D1 -> y, D2 -> x
            switch imgIdx
                case 1
                     app.CentreXEditField.Limits = centreLimits(:,2)';
                     app.CentreYEditField.Limits = centreLimits(:,1)';
                case 2
                     app.CentreXEditField_2.Limits = centreLimits(:,2)';
                     app.CentreYEditField_2.Limits = centreLimits(:,1)';
            end
        end

        %function to adjust offsets to account for change in zoom
        function offsetZoomCompensation(app, imgIdx, imgData, zoomMultiplier)
            
            %get current offsets
            offsets = app.getCurrentOffsets(imgIdx);

            %calculate new centre position
            %Note: fix rounds towards 0 
            offsets = fix((offsets .* zoomMultiplier));

            %as the displayed image size doesn't change the centreLimits do
            %not scale linearly, hence we need to check if this new centre
            %pushes out of range

            %[minD1, minD2; maxD1, maxD2]
            centreLimits = imgData.getCentreLimits();

            %loop through dimensions checking things are okay
            %essentially just checks if they're over the limits in either
            %direction, if they are it sets them too the limit
            for i=1:2
                if offsets(i) > centreLimits(2,i)
                    offsets(i) = centreLimits(2,i);
                elseif offsets(i) < centreLimits(1,i)
                    offsets(i) = centreLimits(1,i);
                end
            end
                
            %update limits to allow new offsets
            app.updateOffsetLimits(imgIdx,centreLimits);
            %Set new offsets
            app.setCurrentOffsets(imgIdx, offsets(1) ,offsets(2));

        end

        %function to handle swapping zoom levels
        function imgData = zoomChanged(app, value, imgIndex,imgData)
            %update this to work aynsc potentially
            
            %get the previous zoom 
            prevZoom = imgData.getCurrentZoom();

            state = imgData.generateDownScaleImage(value);
            
            %here we need to alter the value of the centre as to not fuck
            %it 
            %image controls are also updated during this
            app.offsetZoomCompensation(imgIndex,imgData,(value/prevZoom));
           

            app.updateImageCache(imgIndex);
            if state == 0 %max zoom reached
                f = msgbox("Native Zoom Reached");
            end


        end
        %function to handle changing centre pos
        function centreChanged(app, imgIndex)
            app.updateImageCache(imgIndex);
        end

        %function to handle rotating the image
        function updateImageRotation(app, angleRequested, imgNo)
            %first work out what image we're updating

            switch imgNo
                case 1
                    imgCI = app.img1_CI;
                case 2
                    imgCI = app.img2_CI;
            end
            
            %then we need to call the rotato then we'll update image cache
            imgCI.rotateDownScaleImage(angleRequested);
            app.updateImageCache(imgNo);

        end

        %function to update the translation of the image
        function updateMergeTranslation(app)
            app.img_Merge.translateImage([app.offset_yEditField.Value,app.offset_xEditField.Value]);
            app.updateImageCache(3);
        end

        %function to update the scale of the image
        function updateMergeScale(app)
            app.img_Merge.rescaleImage([app.scale_yEditField.Value,app.scale_xEditField.Value]);
            app.updateImageCache(3);
        end

        %function to handle the refresh of the merge
        function refreshMergeImage(app, imgNo)
            if app.img_Merge_Initiated % check we actually have something there
                
                %get the contrast values 
                if imgNo == 1
                    contrast = app.ContrastSlider.Value;
                    img = app.img1_cache;
                else
                    contrast = app.ContrastSlider_2.Value;
                    img = app.img2_cache;
                end
                %call the image to update
                app.img_Merge.updateImage(img, imgNo, contrast);

            end

        end

        %function to coordinate export
        function exportHandler(app)

            %first we need to convert the values into useful one 
            %now we need to deploy scaling 
            
            %% OLD EXPORT LIBRARY CONNECTION - HAS MEMORY ISSUES DO NOT USE

            %OLD EXPORT LIBRARY with memory issues
            %exportFullMergeAsTiff(app.img1_CI,app.img2_CI, ...
            %    centreOffset_1=[app.CentreYEditField.Value,app.CentreXEditField.Value], ... 
            %    centreOffset_2=[app.CentreYEditField_2.Value, app.CentreXEditField_2.Value], ...
            %    mergeOffset=[app.offset_yEditField.Value, app.offset_xEditField.Value], ...
            %    mergeScale=[app.scale_yEditField.Value,app.scale_xEditField.Value],...
            %    rotation_1=app.RotateEditField.Value, rotation_2=app.RotateEditField_2.Value, ...
            %    useBigTiff=app.BigTIFFCheckBox.Value);
            %%

            %get export path
            [fileName, fileLocation] = uiputfile({'*.tif'});
   
            %exit gracefully if needed
            if fileName == 0
                return
            end
        
            fileName = fileName(1:length(fileName)-4);
            
           
            %get relevant data
            image1Params = struct( ...
                "inputSource","CAT",...
                "imageRotation", app.RotateEditField.Value,...
                "imageZoomRatio", app.img1_CI.getZoomRatio(),...
                "imageCentreOffset", [app.CentreYEditField.Value,app.CentreXEditField.Value], ...
                "imageCentreLoc",mean(app.img_Merge.img1Loc'));
               rawImg1Data = app.img1_CI.getFullScaleImgData();
               

            image2Params = struct(...
                "inputSource","CAT",...
                "imageRotation",app.RotateEditField_2.Value,...
                "imageZoomRatio", app.img2_CI.getZoomRatio(),...
                "imageCentreOffset", [app.CentreYEditField_2.Value, app.CentreXEditField_2.Value],...
                "mergeScale", [app.scale_yEditField.Value,app.scale_xEditField.Value],...
                "imageCentreLoc",mean(app.img_Merge.img2Loc'));
                rawImg2Data = app.img2_CI.getFullScaleImgData();

            displaySize = app.img_Merge.getOverlayedSize();

            %right here we create two of these objects, which condense and
            %standardise their movements, then we can either pass one to
            %the other to combine and export them both or we can do it
            %seperately
            AIE_Image1 = alignedImageExporter(image1Params, displaySize);
            AIE_Image2 = alignedImageExporter(image2Params, displaySize);

            %now we setup exportLimits, the importerdata cell and pass the
            %folder etc

            % Handling to export a smaller area
            if app.useROICheckBox.Value
                %set exportLimits to the limits of the rectangle
                %[xmin, ymin, width, height]
                posData = app.imgMerge_ROI.Position;
                %note d1 = y, d2 = x
                d1Upper = posData(2)+posData(4)-1; % -1 cos inclusive
                d2Upper = posData(1)+posData(3)-1;

                exportLimits = [posData(2), posData(1); d1Upper, d2Upper];
            else
                exportLimits = [1,1;displaySize];
            end

            % rebase the data if we have a non source data type
            % will expand from minVal to maxVal across the data type
            
            rawImg1Data = app.rebaseDataForExport(app.ExportTypeDropDown.Value, app.img1_CI.getImgControlsMetadata(),rawImg1Data);
            rawImg2Data = app.rebaseDataForExport(app.ExportTypeDropDown.Value, app.img2_CI.getImgControlsMetadata(),rawImg2Data);

            %setupExportDataCell manages when we have multiple slices thus
            %are going to produce a bunch of images
            importerDataCell = [app.setupExportDataCell(strcat(fileName,"_ch1"), AIE_Image1, rawImg1Data); ...
                app.setupExportDataCell(strcat(fileName,"_ch2"), AIE_Image2, rawImg2Data)]; %concat data from both images

            %export data, note: exports from the entire datacell so will do
            %both images 
            AIE_Image1.exportToTiledBigTiff(exportLimits,importerDataCell,fileLocation);
            
           
        end

        function rebasedData = rebaseDataForExport(~,targetDataType ,intensityMetaData, imgData)
            %function to rebase the data into a differnet dataType
            % 1) checks if the dataType is different to the one in the
            % data, if different we shortcut
            % 2) if not then we're gonna map the relevant function to a
            % variable and loop through the cell to produce the rebased
            % data cell
            %Expects targetDataType as a class name of a uint[x]
            %Expects IntensityMetaData as the metadataStruct from CI.getImageControlsMetaData
            %Expects image data as a datacell of slice mats

            targetDataType = string(targetDataType); %Fucking char arrays
            imgDataType = string(class(imgData{1}));

            if imgDataType == targetDataType
                rebasedData = imgData; %Shortcut as its already good
                return
            end

            switch targetDataType
                case "Source"
                    %Again shortcut as its already good
                    rebasedData = imgData;
                    return
                case "uint8"
                    conversionFunction = @uint8;
                case "uint16"
                    conversionFunction = @uint16;
                case "uint32"
                    conversionFunction = @uint32;
                case "uint64"
                    conversionFunction = @uint64;
            end
            
            %calculate the new range we need to fit them to
            newLimits = double([intmin(targetDataType),intmax(targetDataType)]);
            newRange = newLimits(2) - newLimits(1);
            oldRange = intensityMetaData.maxIntensity - intensityMetaData.minIntensity;

            %so if we're here we need to convert it to the right datatype
            imgDataCellLength = length(imgData);
            rebasedData = cell(imgDataCellLength,1); %precond the cell
            for i = 1:imgDataCellLength
                %first we need to rebase the data into tthe right type, so
                %we'll convert it to a double so we can do shit, then we'll
                %play with it
                currentSlice = double(imgData{i});
                    
                %Adjust the values into the correct range
                newScaledSlice = (((currentSlice - intensityMetaData.minIntensity) ./ oldRange) .* newRange) + newLimits(1);
                %Convert the datatype and slap it in the new cell
                rebasedData{i} = conversionFunction(newScaledSlice);
            end
        end

        function dataCell = setupExportDataCell(~, fileNameRoot, alignObj, imageDataCell)
            %function to setup an exportDataCell for a specific image, this
            %provides handling for if there are multiple Zs

            nSlices = length(imageDataCell);
            dataCell = cell(nSlices,3);

            for i = 1:nSlices
                if nSlices > 1 %if we have mulitple slices add a z label
                    dataCell{i,1} = strcat(fileNameRoot, "_z",num2str(i));
                else
                    dataCell{i,1} = fileNameRoot;
                end
                
                %populate AIE objcet and the image data
                dataCell{i,2} = alignObj;
                dataCell{i,3} = imageDataCell{i};
            end
        end

        function invert(app,btn,imgNo)
            btnState = btn.Value;

            %select correct CI obj
            if imgNo == 1 
                CI = app.img1_CI;
            else
                CI = app.img2_CI;
            end

            %update text as indicator
            %updateImgCI to be inverted
            if btnState
                btn.Text = "Inverted";
                CI.setInvert(true);
            else
                btn.Text = "Invert";
                CI.setInvert(false);
            end

            %regenerate iamges flipped
            CI.generateDownScaleImage();
            %redraw where needed
            app.updateImageCache(imgNo);

        end

        function mergeImageCentre = getMergeImageDisplaySpaceCentre(app)
            %function to get the coordinates of the centre of the display
            %space in the context of the overlayed merge image 
            %returns [nan nan] if merge image not innitated

            if app.img_Merge_Initiated
                mergeImageSize = size(app.img_Merge.overlayedImage);
                mergeImageBounds = [1,1;mergeImageSize(1),mergeImageSize(2)];
                mergeImageCentre = mean(mergeImageBounds);
            else
                mergeImageCentre = [nan,nan];
            end

        end

        function rectObj = updateROIRectState(app,toggleVal,imgSelectorVal,rectObj, fixedAspectRatio)
            %function to initalise and regulate visibility/interactivity of
            %the bounding rectangle this is an improved wrapper over the
            %effector function so we reroute all throught this

            arguments
                app 
                toggleVal 
                imgSelectorVal 
                rectObj 
                fixedAspectRatio = true;
            end

            activeState = 0;
            if app.ImgSelector.Value == imgSelectorVal && toggleVal == 1
                %We're on the merge image, AND the ROI Checkbox is checked
                activeState = 1;
            end

            if ~isempty(rectObj) %if it exists we want to update it
                rectObj = app.toggleROI(activeState, rectObj,fixedAspectRatio);
            else %if it doesn't exist, we only want to have the prompt to make it when it is needed
                if activeState == 1 
                    rectObj = app.toggleROI(activeState,rectObj,fixedAspectRatio);
                end
            end
           
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            app.LoadButton.Enable = "off";

            %Open up file browser to find an image to load
            [fileName, fileLoc] = uigetfile('*.tif');
            if fileName == 0
                app.LoadButton.Enable = "on";
                return
            end
            
            switch app.ImgSelector.Value
                case "Img1"
                    app.img1_CI = compressedImg(fileLoc,fileName,[400,400]);
                    imgUpdated = 1;
                case "Img2"
                    app.img2_CI = compressedImg(fileLoc,fileName,[400,400]);
                    imgUpdated = 2; 
                otherwise
                    app.LoadButton.Enable = "on";
                    error("ERROR: Unexpected Value for ImgSelector when loading an image")
            end

            %Call functions associated with new data
            app.newDataLoadedUpdates(imgUpdated);
            app.LoadButton.Enable = "on";
        end

        % Value changed function: ImgSelector
        function ImgSelectorValueChanged(app, event)
            value = app.ImgSelector.Value;

            switch value
                case "Img1"
                    app.enableImgSpecifics();
                    app.updateImgTitle(app.img1_CI);
                    app.swapImageSource(1);
                case "Img2"
                    app.enableImgSpecifics();
                    app.updateImgTitle(app.img2_CI);
                    app.swapImageSource(2);
                case "Merge"
                    app.disableImgSpecifics();
                    
                    %has the merge image been initalised?
                    if app.img_Merge_Initiated == false
                        %We need to initalise it
                        
                        %For this to work we need both images initalised
                        if app.img1_CI.getReadyToDisplay() == false || app.img2_CI.getReadyToDisplay() == false
                            msgbox("Please initalise both images before using the merge");
                            return
                        end
                        app.initaliseImgMerge();
                    end
                    
                    %now we swap the image source
                    app.updateMergeImgTitle();
                    app.swapImageSource(3);
                otherwise
                    error("ERROR: Unexpected Value for ImgSelector")
            end
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            %Literally just closes and resets the image
            app.CloseButton.Enable = "off";

            %which are we closing
            switch app.ImgSelector.Value
                case "Img1"
                    app.img1_CI = -1;
                    app.updateImgTitle(app.img1_CI);
                    app.img1_cache = [];
                case "Img2"
                    app.img2_CI = -1;
                    app.updateImgTitle(app.img2_CI);
                    app.img2_cache = [];
                otherwise
                    app.CloseButton.Enable = "on";
                    %error("ERROR: Unexpected Value for ImgSelector when loading an image")
            end


            app.CloseButton.Enable = "on";
        end

        % Value changed function: Rect_SelectButton
        function Rect_SelectButtonValueChanged(app, event)
            %when selected it'll spawn an ROI on the image axis
            %when deselected it'll remove it
            %this is for img1 so will effect img1 & ROI1

            %app.img1_ROI = toggleROI(app,value,app.img1_ROI);
            %Shifted to use new wrapper for toggle ROI
            app.img1_ROI = app.updateROIRectState(app.Rect_SelectButton.Value,"Img1",app.img1_ROI);
        end

        % Value changed function: Rect_SelectButton_2
        function Rect_SelectButton_2ValueChanged(app, event)
           %as above for img2
            app.img2_ROI = app.updateROIRectState(app.Rect_SelectButton_2.Value,"Img2",app.img2_ROI);
        end

        % Callback function
        function ZoomEditFieldValueChanged(app, event)


        end

        % Value changed function: ZoomSpinner
        function ZoomSpinnerValueChanged(app, event)
            value = app.ZoomSpinner.Value;
            app.img1_CI = app.zoomChanged(value,1, app.img1_CI);
        end

        % Value changed function: CentreYEditField_2
        function CentreYEditField_2ValueChanged(app, event)
            app.centreChanged(2)
            
        end

        % Value changed function: CentreXEditField_2
        function CentreXEditField_2ValueChanged(app, event)
            app.centreChanged(2)
        end

        % Value changed function: CentreYEditField
        function CentreYEditFieldValueChanged(app, event)
            app.centreChanged(1)
        end

        % Value changed function: CentreXEditField
        function CentreXEditFieldValueChanged(app, event)
            app.centreChanged(1)
        end

        % Value changed function: ZoomSpinner_2
        function ZoomSpinner_2ValueChanged(app, event)
            value = app.ZoomSpinner_2.Value;
            app.img2_CI = app.zoomChanged(value,2, app.img2_CI);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.updateContrast(1);
                
        end

        % Value changed function: ContrastSlider_2
        function ContrastSlider_2ValueChanged(app, event)
            app.updateContrast(2);

        end

        % Value changed function: ZPosSpinner
        function ZPosSpinnerValueChanged(app, event)
            value = app.ZPosSpinner.Value;
            app.updateImageCache(1);
        end

        % Value changed function: ZPosSpinner_2
        function ZPosSpinner_2ValueChanged(app, event)
            value = app.ZPosSpinner_2.Value;
            app.updateImageCache(2);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            app.cropZoom(1);
        end

        % Button pushed function: CropButton_2
        function CropButton_2Pushed(app, event)
            app.cropZoom(2);
        end

        % Value changed function: RotateEditField
        function RotateEditFieldValueChanged(app, event)
            value = app.RotateEditField.Value;
            app.updateImageRotation(value,1);
        end

        % Value changed function: RotateEditField_2
        function RotateEditField_2ValueChanged(app, event)
            value = app.RotateEditField_2.Value;
            app.updateImageRotation(value,2);
        end

        % Value changed function: scale_yEditField
        function scale_yEditFieldValueChanged(app, event)
            app.updateMergeScale();
        end

        % Value changed function: scale_xEditField
        function scale_xEditFieldValueChanged(app, event)
            app.updateMergeScale();
        end

        % Value changed function: offset_yEditField
        function offset_yEditFieldValueChanged(app, event)
            app.updateMergeTranslation();
            
        end

        % Value changed function: offset_xEditField
        function offset_xEditFieldValueChanged(app, event)
            app.updateMergeTranslation();
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %first see if we have both images loaded
            if ~isempty(app.img1_CI) && ~isempty(app.img2_CI)
                app.exportHandler();
            end
        end

        % Value changed function: InvertButton
        function InvertButtonValueChanged(app, event)
            app.invert(app.InvertButton,1)
        end

        % Value changed function: InvertButton_2
        function InvertButton_2ValueChanged(app, event)
            app.invert(app.InvertButton_2,2)
        end

        % Value changed function: useROICheckBox
        function useROICheckBoxValueChanged(app, event)
            
            %update ROI
            app.imgMerge_ROI = app.updateROIRectState(app.useROICheckBox.Value, "Merge", app.imgMerge_ROI,false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainContainer = uigridlayout(app.UIFigure);
            app.SettingsContainer = uigridlayout(app.MainContainer);
            app.DataPanel = uipanel(app.SettingsContainer);
            app.DataPanel.Title = 'Data';
            app.GridLayout6 = uigridlayout(app.DataPanel);
            app.ImgSelector = uiknob(app.GridLayout6, 'discrete');
            app.ImgSelector.Items = {'Img1', 'Merge', 'Img2'};
            app.ImgSelector.ValueChangedFcn = createCallbackFcn(app, @ImgSelectorValueChanged, true);
            app.ImgSelector.Tooltip = {'Change between displayed image. Load and close buttons will act on the currently displayed image'};
            app.ImgSelector.Value = 'Img1';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.LoadButton = uibutton(app.GridLayout7, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Select data to load in the currently selected image channel'};
            app.LoadButton.Text = 'Load';
            app.CloseButton = uibutton(app.GridLayout7, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Close the currently selected image'};
            app.CloseButton.Text = 'Close';
            app.ExportButton = uibutton(app.GridLayout7, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {'Export the merge result to tiffs'};
            app.ExportButton.Text = 'Export';
            app.useROICheckBox = uicheckbox(app.GridLayout7);
            app.useROICheckBox.ValueChangedFcn = createCallbackFcn(app, @useROICheckBoxValueChanged, true);
            app.useROICheckBox.Tooltip = {'Toggle to define an area within the merge image to export rather than the whole image. This is useful when resultant image sizes are too large for image processors. '};
            app.useROICheckBox.Text = 'use ROI';
            app.ExportTypeDropDownLabel = uilabel(app.GridLayout7);
            app.ExportTypeDropDownLabel.Text = 'Export Type';
            app.ExportTypeDropDown = uidropdown(app.GridLayout7);
            app.ExportTypeDropDown.Items = {'Source', 'uint8', 'uint16', 'uint32', 'uint64'};
            app.ExportTypeDropDown.Tooltip = {'Controls datatype of exported image. Choose source to export as the original datatype, other options will rescale the image data to fit the full range of the data type. Note: many image manipulation programs like FIJI may struggle with large images with high bitdepths'};
            app.ExportTypeDropDown.Value = 'Source';
            app.TabGroup = uitabgroup(app.SettingsContainer);
            app.Img1Tab = uitab(app.TabGroup);
            app.Img1Tab.Title = 'Img1';
            app.GridLayout5 = uigridlayout(app.Img1Tab);
            app.ContrastSliderLabel = uilabel(app.GridLayout5);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.GridLayout5, 'range');
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.CentreXEditFieldLabel = uilabel(app.GridLayout5);
            app.CentreXEditFieldLabel.Text = 'Centre X';
            app.CentreXEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CentreXEditField.ValueChangedFcn = createCallbackFcn(app, @CentreXEditFieldValueChanged, true);
            app.CentreXEditField.Tooltip = {'Shift the centre of the displayed image. Only functions when image is zoomed in '};
            app.CentreYEditFieldLabel = uilabel(app.GridLayout5);
            app.CentreYEditFieldLabel.Text = 'Centre Y';
            app.CentreYEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CentreYEditField.ValueChangedFcn = createCallbackFcn(app, @CentreYEditFieldValueChanged, true);
            app.CentreYEditField.Tooltip = {'Shift the centre of the displayed image. Only functions when image is zoomed in '};
            app.Rect_SelectButton = uibutton(app.GridLayout5, 'state');
            app.Rect_SelectButton.ValueChangedFcn = createCallbackFcn(app, @Rect_SelectButtonValueChanged, true);
            app.Rect_SelectButton.Tooltip = {'Enables a rectangle to zoom specifically into an image part'};
            app.Rect_SelectButton.Text = 'Rect_Select';
            app.CropButton = uibutton(app.GridLayout5, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Tooltip = {'Crops image to Rect_Select rectangle'};
            app.CropButton.Text = 'Crop';
            app.ZPosSpinnerLabel = uilabel(app.GridLayout5);
            app.ZPosSpinnerLabel.Text = 'Z Pos';
            app.ZPosSpinner = uispinner(app.GridLayout5);
            app.ZPosSpinner.ValueChangedFcn = createCallbackFcn(app, @ZPosSpinnerValueChanged, true);
            app.ZPosSpinner.Tooltip = {'Move through slices of images with more than 2 dimensions'};
            app.ZoomSpinnerLabel = uilabel(app.GridLayout5);
            app.ZoomSpinnerLabel.Text = 'Zoom';
            app.ZoomSpinner = uispinner(app.GridLayout5);
            app.ZoomSpinner.Limits = [1 Inf];
            app.ZoomSpinner.RoundFractionalValues = 'on';
            app.ZoomSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoomSpinnerValueChanged, true);
            app.ZoomSpinner.Tooltip = {'Alter the level of displayed zoom'};
            app.ZoomSpinner.Value = 1;
            app.RotateEditFieldLabel = uilabel(app.GridLayout5);
            app.RotateEditFieldLabel.Text = 'Rotate';
            app.RotateEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RotateEditField.Limits = [-180 180];
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @RotateEditFieldValueChanged, true);
            app.RotateEditField.Tooltip = {'Rotate the current image around the true centre of the image'};
            app.InvertButton = uibutton(app.GridLayout5, 'state');
            app.InvertButton.ValueChangedFcn = createCallbackFcn(app, @InvertButtonValueChanged, true);
            app.InvertButton.Tooltip = {'Inverts the axis of the image'};
            app.InvertButton.Text = 'Invert';
            app.Img2Tab = uitab(app.TabGroup);
            app.Img2Tab.Title = 'Img2';
            app.GridLayout5_2 = uigridlayout(app.Img2Tab);
            app.ContrastSlider_2Label = uilabel(app.GridLayout5_2);
            app.ContrastSlider_2Label.Text = 'Contrast';
            app.ContrastSlider_2 = uislider(app.GridLayout5_2, 'range');
            app.ContrastSlider_2.ValueChangedFcn = createCallbackFcn(app, @ContrastSlider_2ValueChanged, true);
            app.CentreXEditField_2Label = uilabel(app.GridLayout5_2);
            app.CentreXEditField_2Label.Text = 'Centre X';
            app.CentreXEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.CentreXEditField_2.ValueChangedFcn = createCallbackFcn(app, @CentreXEditField_2ValueChanged, true);
            app.CentreXEditField_2.Tooltip = {'Shift the centre of the displayed image. Only functions when image is zoomed in '};
            app.CentreYEditField_2Label = uilabel(app.GridLayout5_2);
            app.CentreYEditField_2Label.Text = 'Centre Y';
            app.CentreYEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.CentreYEditField_2.ValueChangedFcn = createCallbackFcn(app, @CentreYEditField_2ValueChanged, true);
            app.CentreYEditField_2.Tooltip = {'Shift the centre of the displayed image. Only functions when image is zoomed in '};
            app.Rect_SelectButton_2 = uibutton(app.GridLayout5_2, 'state');
            app.Rect_SelectButton_2.ValueChangedFcn = createCallbackFcn(app, @Rect_SelectButton_2ValueChanged, true);
            app.Rect_SelectButton_2.Tooltip = {'Enables a rectangle to zoom specifically into an image part'};
            app.Rect_SelectButton_2.Text = 'Rect_Select';
            app.CropButton_2 = uibutton(app.GridLayout5_2, 'push');
            app.CropButton_2.ButtonPushedFcn = createCallbackFcn(app, @CropButton_2Pushed, true);
            app.CropButton_2.Tooltip = {'Crops image to Rect_Select rectangle'};
            app.CropButton_2.Text = 'Crop';
            app.ZPosSpinner_2Label = uilabel(app.GridLayout5_2);
            app.ZPosSpinner_2Label.Text = 'Z Pos';
            app.ZPosSpinner_2 = uispinner(app.GridLayout5_2);
            app.ZPosSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ZPosSpinner_2ValueChanged, true);
            app.ZPosSpinner_2.Tooltip = {'Move through slices of images with more than 2 dimensions'};
            app.ZoomSpinner_2Label = uilabel(app.GridLayout5_2);
            app.ZoomSpinner_2Label.Text = 'Zoom';
            app.ZoomSpinner_2 = uispinner(app.GridLayout5_2);
            app.ZoomSpinner_2.Limits = [1 Inf];
            app.ZoomSpinner_2.RoundFractionalValues = 'on';
            app.ZoomSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ZoomSpinner_2ValueChanged, true);
            app.ZoomSpinner_2.Tooltip = {'Alter the level of displayed zoom'};
            app.ZoomSpinner_2.Value = 1;
            app.RotateEditField_2Label = uilabel(app.GridLayout5_2);
            app.RotateEditField_2Label.Text = 'Rotate';
            app.RotateEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.RotateEditField_2.Limits = [-180 180];
            app.RotateEditField_2.ValueChangedFcn = createCallbackFcn(app, @RotateEditField_2ValueChanged, true);
            app.RotateEditField_2.Tooltip = {'Rotate the current image around the true centre of the image'};
            app.InvertButton_2 = uibutton(app.GridLayout5_2, 'state');
            app.InvertButton_2.ValueChangedFcn = createCallbackFcn(app, @InvertButton_2ValueChanged, true);
            app.InvertButton_2.Tooltip = {'Inverts the axis of the image'};
            app.InvertButton_2.Text = 'Invert';
            app.TranslateTab = uitab(app.TabGroup);
            app.TranslateTab.Title = 'Translate';
            app.GridLayout4 = uigridlayout(app.TranslateTab);
            app.offset_xEditFieldLabel = uilabel(app.GridLayout4);
            app.offset_xEditFieldLabel.Text = 'offset_x';
            app.offset_xEditField = uieditfield(app.GridLayout4, 'numeric');
            app.offset_xEditField.ValueChangedFcn = createCallbackFcn(app, @offset_xEditFieldValueChanged, true);
            app.offset_xEditField.Tooltip = {'Displaces images in x'};
            app.offset_yEditFieldLabel = uilabel(app.GridLayout4);
            app.offset_yEditFieldLabel.Text = 'offset_y';
            app.offset_yEditField = uieditfield(app.GridLayout4, 'numeric');
            app.offset_yEditField.ValueChangedFcn = createCallbackFcn(app, @offset_yEditFieldValueChanged, true);
            app.offset_yEditField.Tooltip = {'Displaces images in y'};
            app.scale_xEditFieldLabel = uilabel(app.GridLayout4);
            app.scale_xEditFieldLabel.Text = 'scale_x';
            app.scale_xEditField = uieditfield(app.GridLayout4, 'numeric');
            app.scale_xEditField.Limits = [1e-05 Inf];
            app.scale_xEditField.ValueChangedFcn = createCallbackFcn(app, @scale_xEditFieldValueChanged, true);
            app.scale_xEditField.Tooltip = {'Changes the relative x scaling of images'};
            app.scale_xEditField.Value = 1;
            app.scale_yEditFieldLabel = uilabel(app.GridLayout4);
            app.scale_yEditFieldLabel.Text = 'scale_y';
            app.scale_yEditField = uieditfield(app.GridLayout4, 'numeric');
            app.scale_yEditField.Limits = [1e-05 Inf];
            app.scale_yEditField.ValueChangedFcn = createCallbackFcn(app, @scale_yEditFieldValueChanged, true);
            app.scale_yEditField.Tooltip = {'Changes the relative y scaling of images'};
            app.scale_yEditField.Value = 1;
            app.ImgContainer = uigridlayout(app.MainContainer);
            app.DisplayAxis = uiaxes(app.ImgContainer);
            xlabel(app.DisplayAxis, 'X')
            ylabel(app.DisplayAxis, 'Y')
            zlabel(app.DisplayAxis, 'Z')
            app.CurrentlyShownTitle = uilabel(app.ImgContainer);
            app.CurrentlyShownTitle.Text = 'No Data Loaded in Img_1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrossAlignmentTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end